classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WedgesFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        WedgesFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        ConstraintsFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        ConstraintsFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        StrategyFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        StrategyFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        WedgesDataTextAreaLabel       matlab.ui.control.Label
        WedgesDataTextArea            matlab.ui.control.TextArea
        ConstraintsDataTextAreaLabel  matlab.ui.control.Label
        ConstraintsDataTextArea       matlab.ui.control.TextArea
        StrategyDataTextAreaLabel     matlab.ui.control.Label
        StrategyDataTextArea          matlab.ui.control.TextArea
        SubmitButton                  matlab.ui.control.Button
        SubmitButton_2                matlab.ui.control.Button
        SubmitButton_3                matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            value = app.WedgesFileNameWithoutQuotesEditField.Value;
            data = {};
            % Initialize variables
            
            try
                if strcmp(value,'wedges.txt')
                    % If the correct file is selected, extract each line
                    % May want to use strsplit to extract data
                    FID = fopen(value);
                    while ~feof(FID)
                        x = {fgetl(FID)};
                        data = [data; x];
                    end
                    app.WedgesDataTextArea.Value = data;
                    % Display the data in the appropriate text area
                else
                    app.WedgesDataTextArea.Value = 'Error: Incorrect file name entered';
                    % If the wrong file is selected, display error message
                end
            catch
                app.WedgesDataTextArea.Value = 'Error: File not found in MATLAB path';
                % If the file is not in the MATLAB path, display the error message
            end
        end

        % Button pushed function: SubmitButton_2
        function SubmitButton_2Pushed(app, event)
            value = app.ConstraintsFileNameWithoutQuotesEditField.Value;
            data = {};
            % Initialize variables
            
            try
                if strcmp(value,'constraints.txt')
                    % If the correct file is selected, extract each line
                    % May want to use strsplit to extract data
                    FID = fopen(value);
                    while ~feof(FID)
                        x = {fgetl(FID)};
                        data = [data; x];
                    end
                    app.ConstraintsDataTextArea.Value = data;
                    % Display the data in the appropriate text area
                else
                    app.ConstraintsDataTextArea.Value = 'Error: Incorrect file name entered';
                    % If the wrong file is selected, display error message
                end
            catch
                app.ConstraintsDataTextArea.Value = 'Error: File not found in MATLAB path';
                % If the file is not in the MATLAB path, display error message
            end
        end

        % Button pushed function: SubmitButton_3
        function SubmitButton_3Pushed(app, event)
            value = app.StrategyFileNameWithoutQuotesEditField.Value;
            data = {};
            % Initialize variables
            
            try
                if strcmp(value,'strategy.txt')
                    % If the correct file is selected, extract each line
                    % May want to use strsplit to extract data
                    FID = fopen(value);
                    while ~feof(FID)
                        x = {fgetl(FID)};
                        data = [data; x];
                    end
                    app.StrategyDataTextArea.Value = data;
                    % Display the data in the appropriate text area
                else
                    app.StrategyDataTextArea.Value = 'Error: Incorrect file name entered';
                    % If the wrong file is selected, display error message
                end
            catch
                app.StrategyDataTextArea.Value = 'Error: File not found in MATLAB path';
                % If the file is not in the MATLAB path, display error message
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WedgesFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.WedgesFileNameWithoutQuotesEditFieldLabel.Text = 'Wedges File Name (Without Quotes)';
            app.WedgesFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.ConstraintsFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintsFileNameWithoutQuotesEditFieldLabel.Text = 'Constraints File Name (Without Quotes)';
            app.ConstraintsFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.StrategyFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.StrategyFileNameWithoutQuotesEditFieldLabel.Text = 'Strategy File Name (Without Quotes)';
            app.StrategyFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.WedgesDataTextAreaLabel = uilabel(app.UIFigure);
            app.WedgesDataTextAreaLabel.Text = 'Wedges Data';
            app.WedgesDataTextArea = uitextarea(app.UIFigure);
            app.ConstraintsDataTextAreaLabel = uilabel(app.UIFigure);
            app.ConstraintsDataTextAreaLabel.Text = 'Constraints Data';
            app.ConstraintsDataTextArea = uitextarea(app.UIFigure);
            app.StrategyDataTextAreaLabel = uilabel(app.UIFigure);
            app.StrategyDataTextAreaLabel.Text = 'Strategy Data';
            app.StrategyDataTextArea = uitextarea(app.UIFigure);
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.SubmitButton_2 = uibutton(app.UIFigure, 'push');
            app.SubmitButton_2.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_2Pushed, true);
            app.SubmitButton_2.Text = 'Submit';
            app.SubmitButton_3 = uibutton(app.UIFigure, 'push');
            app.SubmitButton_3.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_3Pushed, true);
            app.SubmitButton_3.Text = 'Submit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end